classdef appIn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BottomEmittingOLEDSimulatorUIFigure  matlab.ui.Figure
        AnisotropyEditFieldLabel      matlab.ui.control.Label
        AnisotropyEditField           matlab.ui.control.NumericEditField
        ETLThicknessEditFieldLabel    matlab.ui.control.Label
        ETLThicknessEditField         matlab.ui.control.NumericEditField
        RunButton                     matlab.ui.control.Button
        MaxInPlaneWavevectorValueEditFieldLabel  matlab.ui.control.Label
        MaxInPlaneWavevectorValueEditField  matlab.ui.control.NumericEditField
        InPlaneWavevectorSweepResolutionEditFieldLabel  matlab.ui.control.Label
        InPlaneWavevectorSweepResolutionEditField  matlab.ui.control.NumericEditField
        Layer1DropDownLabel           matlab.ui.control.Label
        Layer1DropDown                matlab.ui.control.DropDown
        Layer2DropDownLabel           matlab.ui.control.Label
        Layer2DropDown                matlab.ui.control.DropDown
        Layer3DropDownLabel           matlab.ui.control.Label
        Layer3DropDown                matlab.ui.control.DropDown
        Layer4DropDownLabel           matlab.ui.control.Label
        Layer4DropDown                matlab.ui.control.DropDown
        Layer5DropDownLabel           matlab.ui.control.Label
        Layer5DropDown                matlab.ui.control.DropDown
        Layer6DropDownLabel           matlab.ui.control.Label
        Layer6DropDown                matlab.ui.control.DropDown
        Layer7DropDownLabel           matlab.ui.control.Label
        Layer7DropDown                matlab.ui.control.DropDown
        Layer8DropDownLabel           matlab.ui.control.Label
        Layer8DropDown                matlab.ui.control.DropDown
        EML                           matlab.ui.container.ButtonGroup
        Button2                       matlab.ui.control.RadioButton
        Button3                       matlab.ui.control.RadioButton
        Button4                       matlab.ui.control.RadioButton
        Button5                       matlab.ui.control.RadioButton
        Button6                       matlab.ui.control.RadioButton
        Button7                       matlab.ui.control.RadioButton
        Button8                       matlab.ui.control.RadioButton
        Button9                       matlab.ui.control.RadioButton
        InitialEditFieldLabel         matlab.ui.control.Label
        AInitialEditField             matlab.ui.control.NumericEditField
        FinalEditFieldLabel           matlab.ui.control.Label
        AFinalEditField               matlab.ui.control.NumericEditField
        StepEditFieldLabel            matlab.ui.control.Label
        AStepEditField                matlab.ui.control.NumericEditField
        ParameterALabel               matlab.ui.control.Label
        ParameterBLabel               matlab.ui.control.Label
        InitialEditFieldLabel_2       matlab.ui.control.Label
        BInitialEditField             matlab.ui.control.NumericEditField
        FinalEditFieldLabel_2         matlab.ui.control.Label
        BFinalEditField               matlab.ui.control.NumericEditField
        StepEditFieldLabel_2          matlab.ui.control.Label
        BStepEditField                matlab.ui.control.NumericEditField
        ParameterCLabel               matlab.ui.control.Label
        InitialEditFieldLabel_3       matlab.ui.control.Label
        CInitialEditField             matlab.ui.control.NumericEditField
        FinalEditFieldLabel_3         matlab.ui.control.Label
        CFinalEditField               matlab.ui.control.NumericEditField
        StepEditFieldLabel_3          matlab.ui.control.Label
        CStepEditField                matlab.ui.control.NumericEditField
        MaterialLabel                 matlab.ui.control.Label
        ThicknessLabel                matlab.ui.control.Label
        EMLLabel                      matlab.ui.control.Label
        PlotspectrumvviewingangleCheckBox  matlab.ui.control.CheckBox
        PlotintensityvviewingangleCheckBox  matlab.ui.control.CheckBox
        QuantumYieldEditField_2Label  matlab.ui.control.Label
        QuantumYieldField             matlab.ui.control.EditField
        DipoleSheetLocationEditField_2Label  matlab.ui.control.Label
        DipoleSheetField              matlab.ui.control.EditField
        Thick1Field                   matlab.ui.control.EditField
        Thick2Field                   matlab.ui.control.EditField
        Thick3Field                   matlab.ui.control.EditField
        Thick4Field                   matlab.ui.control.EditField
        Thick5Field                   matlab.ui.control.EditField
        Thick6Field                   matlab.ui.control.EditField
        Thick7Field                   matlab.ui.control.EditField
        Thick8Field                   matlab.ui.control.EditField
        Wavelength1EditField          matlab.ui.control.NumericEditField
        nmLabel                       matlab.ui.control.Label
        PlotspectralpowerdensityatCheckBox  matlab.ui.control.CheckBox
        PlotpowerdissipationspectrumatCheckBox  matlab.ui.control.CheckBox
        Wavelength2EditField          matlab.ui.control.NumericEditField
        nmLabel_2                     matlab.ui.control.Label
        Thick9Field                   matlab.ui.control.EditField
        Layer9DropDownLabel           matlab.ui.control.Label
        Layer9DropDown                matlab.ui.control.DropDown
        Thick10Field                  matlab.ui.control.EditField
        Layer10DropDownLabel          matlab.ui.control.Label
        Layer10DropDown               matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Close all previous figures
            close all;
            
            % Determine EML location
            selectedButton = app.EML.SelectedObject;
            
            if(selectedButton == app.Button2)
                emlLoc = 2;
            end
            if(selectedButton == app.Button3)
                emlLoc = 3;
            end
            if(selectedButton == app.Button4)
                emlLoc = 4;
            end
            if(selectedButton == app.Button5)
                emlLoc = 5;
            end
            if(selectedButton == app.Button6)
                emlLoc = 6;
            end
            if(selectedButton == app.Button7)
                emlLoc = 7;
            end
            if(selectedButton == app.Button8)
                emlLoc = 8;
            end
            if(selectedButton == app.Button9)
                emlLoc = 9;
            end
            
            % Pass input values to simulation function
            % Alternate values: Irppy2acac=.23;IrMDQ2acac=.21;FIrpic=.24
            aniso = app.AnisotropyEditField.Value;
            
            etlThick = app.ETLThicknessEditField.Value;
            angleMax = app.MaxInPlaneWavevectorValueEditField.Value;
            angleRes = app.InPlaneWavevectorSweepResolutionEditField.Value;
            
            % Set layer materials
            lay1 = app.Layer1DropDown.Value;
            lay2 = app.Layer2DropDown.Value;
            lay3 = app.Layer3DropDown.Value;
            lay4 = app.Layer4DropDown.Value;
            lay5 = app.Layer5DropDown.Value;
            lay6 = app.Layer6DropDown.Value;
            lay7 = app.Layer7DropDown.Value;
            lay8 = app.Layer8DropDown.Value;
            lay9 = app.Layer9DropDown.Value;
            lay10 = app.Layer10DropDown.Value;
            
            % Read values for param_A, param_B, param_C
            aInit = app.AInitialEditField.Value;
            aFin = app.AFinalEditField.Value;
            aStep = app.AStepEditField.Value;
            bInit = app.BInitialEditField.Value;
            bFin = app.BFinalEditField.Value;
            bStep = app.BStepEditField.Value;
            cInit = app.CInitialEditField.Value;
            cFin = app.CFinalEditField.Value;
            cStep = app.CStepEditField.Value;
            
            % Set QY value
            if( strcmp(app.QuantumYieldField.Value,'param_A') .... 
                    || strcmp(app.QuantumYieldField.Value,'param_B') ...
                    || strcmp(app.QuantumYieldField.Value,'param_C'))
                quantumY = app.QuantumYieldField.Value;
            else
                quantumY = str2double(app.QuantumYieldField.Value);
            end
            
            % Set dipole sheet location value
            if( strcmp(app.DipoleSheetField.Value,'param_A') .... 
                    || strcmp(app.DipoleSheetField.Value,'param_B') ...
                    || strcmp(app.DipoleSheetField.Value,'param_C'))
                dipoleLoc = app.DipoleSheetField.Value;
            else
                dipoleLoc = str2double(app.DipoleSheetField.Value);
            end
            
            % Set thickness values
            if( strcmp(app.Thick1Field.Value,'param_A') .... 
                    || strcmp(app.Thick1Field.Value,'param_B') ...
                    || strcmp(app.Thick1Field.Value,'param_C'))
                thick1 = app.Thick1Field.Value;
            else
                thick1 = str2double(app.Thick1Field.Value);
            end
            if( strcmp(app.Thick2Field.Value,'param_A') .... 
                    || strcmp(app.Thick2Field.Value,'param_B') ...
                    || strcmp(app.Thick2Field.Value,'param_C'))
                thick2 = app.Thick2Field.Value;
            else
                thick2 = str2double(app.Thick2Field.Value);
            end
            if( strcmp(app.Thick3Field.Value,'param_A') .... 
                    || strcmp(app.Thick3Field.Value,'param_B') ...
                    || strcmp(app.Thick3Field.Value,'param_C'))
                thick3 = app.Thick3Field.Value;
            else
                thick3 = str2double(app.Thick3Field.Value);
            end
            if( strcmp(app.Thick4Field.Value,'param_A') .... 
                    || strcmp(app.Thick4Field.Value,'param_B') ...
                    || strcmp(app.Thick4Field.Value,'param_C'))
                thick4 = app.Thick4Field.Value;
            else
                thick4 = str2double(app.Thick4Field.Value);
            end
            if( strcmp(app.Thick5Field.Value,'param_A') .... 
                    || strcmp(app.Thick5Field.Value,'param_B') ...
                    || strcmp(app.Thick5Field.Value,'param_C'))
                thick5 = app.Thick5Field.Value;
            else
                thick5 = str2double(app.Thick5Field.Value);
            end
            if( strcmp(app.Thick6Field.Value,'param_A') .... 
                    || strcmp(app.Thick6Field.Value,'param_B') ...
                    || strcmp(app.Thick6Field.Value,'param_C'))
                thick6 = app.Thick6Field.Value;
            else
                thick6 = str2double(app.Thick6Field.Value);
            end
            if( strcmp(app.Thick7Field.Value,'param_A') .... 
                    || strcmp(app.Thick7Field.Value,'param_B') ...
                    || strcmp(app.Thick7Field.Value,'param_C'))
                thick7 = app.Thick7Field.Value;
            else
                thick7 = str2double(app.Thick7Field.Value);
            end
            if( strcmp(app.Thick8Field.Value,'param_A') .... 
                    || strcmp(app.Thick8Field.Value,'param_B') ...
                    || strcmp(app.Thick8Field.Value,'param_C'))
                thick8 = app.Thick8Field.Value;
            else
                thick8 = str2double(app.Thick8Field.Value);
            end
            if( strcmp(app.Thick9Field.Value,'param_A') .... 
                    || strcmp(app.Thick9Field.Value,'param_B') ...
                    || strcmp(app.Thick9Field.Value,'param_C'))
                thick9 = app.Thick9Field.Value;
            else
                thick9 = str2double(app.Thick9Field.Value);
            end
            if( strcmp(app.Thick10Field.Value,'param_A') .... 
                    || strcmp(app.Thick10Field.Value,'param_B') ...
                    || strcmp(app.Thick10Field.Value,'param_C'))
                thick10 = app.Thick10Field.Value;
            else
                thick10 = str2double(app.Thick10Field.Value);
            end
            
            % Select plots to send to output
            fig1On = app.PlotspectrumvviewingangleCheckBox.Value;
            fig2On = app.PlotintensityvviewingangleCheckBox.Value;
            fig3On = app.PlotspectralpowerdensityatCheckBox.Value;
            wav1 = app.Wavelength1EditField.Value;
            fig4On = app.PlotpowerdissipationspectrumatCheckBox.Value;
            wav2 = app.Wavelength2EditField.Value;
            
            % Send parameters from UI to function
            calculate(aniso, etlThick, emlLoc, dipoleLoc, ...
                angleMax, angleRes, quantumY, ...
                lay1, lay2, lay3, lay4, lay5, lay6, lay7, lay8, lay9, lay10, ...
                thick1, thick2, thick3, thick4, thick5, thick6, thick7, thick8, thick9, thick10, ...
                aInit, aFin, aStep, bInit, bFin, bStep, cInit, cFin, cStep, ...
                fig1On, fig2On, fig3On, wav1, fig4On, wav2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BottomEmittingOLEDSimulatorUIFigure = uifigure('Visible', 'off');
            app.BottomEmittingOLEDSimulatorUIFigure.Name = 'Bottom-Emitting OLED Simulator';
            app.AnisotropyEditFieldLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.AnisotropyEditFieldLabel.Text = 'Anisotropy';
            app.AnisotropyEditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.AnisotropyEditField.Value = 0.33333;
            app.ETLThicknessEditFieldLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.ETLThicknessEditFieldLabel.Text = 'ETL Thickness';
            app.ETLThicknessEditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.ETLThicknessEditField.Value = 40;
            app.RunButton = uibutton(app.BottomEmittingOLEDSimulatorUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.MaxInPlaneWavevectorValueEditFieldLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.MaxInPlaneWavevectorValueEditFieldLabel.Text = 'Max In-Plane Wavevector Value ';
            app.MaxInPlaneWavevectorValueEditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.MaxInPlaneWavevectorValueEditField.Value = 2001;
            app.InPlaneWavevectorSweepResolutionEditFieldLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.InPlaneWavevectorSweepResolutionEditFieldLabel.Text = 'In-Plane Wavevector Sweep Resolution';
            app.InPlaneWavevectorSweepResolutionEditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.InPlaneWavevectorSweepResolutionEditField.Value = 1000;
            app.Layer1DropDownLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer1DropDownLabel.Text = 'Layer 1';
            app.Layer1DropDown = uidropdown(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer1DropDown.Items = {'N/A', 'Ag', 'Air', 'Al', 'Alq3', 'BAlq', 'Bphen', 'CBP', 'Glass', 'ITO', 'MoO3', 'NPB', 'NDP9', 'NHT5', 'NHT49', 'NHT49_NDP9_10pr', 'Spiro_TAD', 'TPBi', 'PolyTPD', 'MAPbI'};
            app.Layer1DropDown.Value = 'Glass';
            app.Layer2DropDownLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer2DropDownLabel.Text = 'Layer 2';
            app.Layer2DropDown = uidropdown(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer2DropDown.Items = {'N/A', 'Ag', 'Air', 'Al', 'Alq3', 'BAlq', 'Bphen', 'CBP', 'Glass', 'ITO', 'MoO3', 'NPB', 'NDP9', 'NHT5', 'NHT49', 'NHT49_NDP9_10pr', 'Spiro_TAD', 'TPBi', 'PolyTPD', 'MAPbI'};
            app.Layer2DropDown.Value = 'ITO';
            app.Layer3DropDownLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer3DropDownLabel.Text = 'Layer 3';
            app.Layer3DropDown = uidropdown(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer3DropDown.Items = {'N/A', 'Ag', 'Air', 'Al', 'Alq3', 'BAlq', 'Bphen', 'CBP', 'Glass', 'ITO', 'MoO3', 'NPB', 'NDP9', 'NHT5', 'NHT49', 'NHT49_NDP9_10pr', 'Spiro_TAD', 'TPBi', 'PolyTPD', 'MAPbI'};
            app.Layer3DropDown.Value = 'PolyTPD';
            app.Layer4DropDownLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer4DropDownLabel.Text = 'Layer 4';
            app.Layer4DropDown = uidropdown(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer4DropDown.Items = {'N/A', 'Ag', 'Air', 'Al', 'Alq3', 'BAlq', 'Bphen', 'CBP', 'Glass', 'ITO', 'MoO3', 'NPB', 'NDP9', 'NHT5', 'NHT49', 'NHT49_NDP9_10pr', 'Spiro_TAD', 'TPBi', 'PolyTPD', 'MAPbI'};
            app.Layer4DropDown.Value = 'MAPbI';
            app.Layer5DropDownLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer5DropDownLabel.Text = 'Layer 5';
            app.Layer5DropDown = uidropdown(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer5DropDown.Items = {'N/A', 'Ag', 'Air', 'Al', 'Alq3', 'BAlq', 'Bphen', 'CBP', 'Glass', 'ITO', 'MoO3', 'NPB', 'NDP9', 'NHT5', 'NHT49', 'NHT49_NDP9_10pr', 'Spiro_TAD', 'TPBi', 'PolyTPD', 'MAPbI'};
            app.Layer5DropDown.Value = 'TPBi';
            app.Layer6DropDownLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer6DropDownLabel.Text = 'Layer 6';
            app.Layer6DropDown = uidropdown(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer6DropDown.Items = {'N/A', 'Ag', 'Air', 'Al', 'Alq3', 'BAlq', 'Bphen', 'CBP', 'Glass', 'ITO', 'MoO3', 'NPB', 'NDP9', 'NHT5', 'NHT49', 'NHT49_NDP9_10pr', 'Spiro_TAD', 'TPBi', 'PolyTPD', 'MAPbI'};
            app.Layer6DropDown.Value = 'Al';
            app.Layer7DropDownLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer7DropDownLabel.Text = 'Layer 7';
            app.Layer7DropDown = uidropdown(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer7DropDown.Items = {'N/A', 'Ag', 'Air', 'Al', 'Alq3', 'BAlq', 'Bphen', 'CBP', 'Glass', 'ITO', 'MoO3', 'NPB', 'NDP9', 'NHT5', 'NHT49', 'NHT49_NDP9_10pr', 'Spiro_TAD', 'TPBi', 'PolyTPD', 'MAPbI'};
            app.Layer7DropDown.Value = 'Air';
            app.Layer8DropDownLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer8DropDownLabel.Text = 'Layer 8';
            app.Layer8DropDown = uidropdown(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer8DropDown.Items = {'N/A', 'Ag', 'Air', 'Al', 'Alq3', 'BAlq', 'Bphen', 'CBP', 'Glass', 'ITO', 'MoO3', 'NPB', 'NDP9', 'NHT5', 'NHT49', 'NHT49_NDP9_10pr', 'Spiro_TAD', 'TPBi', 'PolyTPD', 'MAPbI'};
            app.Layer8DropDown.Value = 'N/A';
            app.EML = uibuttongroup(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Button2 = uiradiobutton(app.EML);
            app.Button2.Text = '';
            app.Button3 = uiradiobutton(app.EML);
            app.Button3.Text = '';
            app.Button4 = uiradiobutton(app.EML);
            app.Button4.Text = '';
            app.Button4.Value = true;
            app.Button5 = uiradiobutton(app.EML);
            app.Button5.Text = '';
            app.Button6 = uiradiobutton(app.EML);
            app.Button6.Text = '';
            app.Button7 = uiradiobutton(app.EML);
            app.Button7.Text = '';
            app.Button8 = uiradiobutton(app.EML);
            app.Button8.Text = '';
            app.Button9 = uiradiobutton(app.EML);
            app.Button9.Text = '';
            app.InitialEditFieldLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.InitialEditFieldLabel.Text = 'Initial';
            app.AInitialEditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.AInitialEditField.Value = 50;
            app.FinalEditFieldLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.FinalEditFieldLabel.Text = 'Final';
            app.AFinalEditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.AFinalEditField.Value = 50;
            app.StepEditFieldLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.StepEditFieldLabel.Text = 'Step';
            app.AStepEditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.AStepEditField.Value = 10;
            app.ParameterALabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.ParameterALabel.Text = 'Parameter A';
            app.ParameterBLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.ParameterBLabel.Text = 'Parameter B';
            app.InitialEditFieldLabel_2 = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.InitialEditFieldLabel_2.Text = 'Initial';
            app.BInitialEditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.BInitialEditField.Value = 20;
            app.FinalEditFieldLabel_2 = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.FinalEditFieldLabel_2.Text = 'Final';
            app.BFinalEditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.BFinalEditField.Value = 20;
            app.StepEditFieldLabel_2 = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.StepEditFieldLabel_2.Text = 'Step';
            app.BStepEditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.BStepEditField.Value = 20;
            app.ParameterCLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.ParameterCLabel.Text = 'Parameter C';
            app.InitialEditFieldLabel_3 = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.InitialEditFieldLabel_3.Text = 'Initial';
            app.CInitialEditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.CInitialEditField.Value = 60;
            app.FinalEditFieldLabel_3 = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.FinalEditFieldLabel_3.Text = 'Final';
            app.CFinalEditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.CFinalEditField.Value = 60;
            app.StepEditFieldLabel_3 = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.StepEditFieldLabel_3.Text = 'Step';
            app.CStepEditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.CStepEditField.Value = 10;
            app.MaterialLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.MaterialLabel.Text = 'Material';
            app.ThicknessLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.ThicknessLabel.Text = 'Thickness';
            app.EMLLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.EMLLabel.Text = 'EML';
            app.PlotspectrumvviewingangleCheckBox = uicheckbox(app.BottomEmittingOLEDSimulatorUIFigure);
            app.PlotspectrumvviewingangleCheckBox.Text = 'Plot spectrum v. viewing angle';
            app.PlotspectrumvviewingangleCheckBox.Value = true;
            app.PlotintensityvviewingangleCheckBox = uicheckbox(app.BottomEmittingOLEDSimulatorUIFigure);
            app.PlotintensityvviewingangleCheckBox.Text = 'Plot intensity v. viewing angle';
            app.PlotintensityvviewingangleCheckBox.Value = true;
            app.QuantumYieldEditField_2Label = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.QuantumYieldEditField_2Label.Text = 'Quantum Yield';
            app.QuantumYieldField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'text');
            app.QuantumYieldField.Value = '1';
            app.DipoleSheetLocationEditField_2Label = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.DipoleSheetLocationEditField_2Label.Text = 'Dipole Sheet Location';
            app.DipoleSheetField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'text');
            app.DipoleSheetField.Value = '0';
            app.Thick1Field = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'text');
            app.Thick1Field.Value = 'Inf';
            app.Thick2Field = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'text');
            app.Thick2Field.Value = '150';
            app.Thick3Field = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'text');
            app.Thick3Field.Value = '20';
            app.Thick4Field = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'text');
            app.Thick4Field.Value = '40';
            app.Thick5Field = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'text');
            app.Thick5Field.Value = '40';
            app.Thick6Field = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'text');
            app.Thick6Field.Value = '100';
            app.Thick7Field = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'text');
            app.Thick7Field.Value = 'Inf';
            app.Thick8Field = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'text');
            app.Thick8Field.Value = 'NaN';
            app.Wavelength1EditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.Wavelength1EditField.Value = 500;
            app.nmLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.nmLabel.Text = 'nm';
            app.PlotspectralpowerdensityatCheckBox = uicheckbox(app.BottomEmittingOLEDSimulatorUIFigure);
            app.PlotspectralpowerdensityatCheckBox.Text = 'Plot spectral power density at';
            app.PlotspectralpowerdensityatCheckBox.Value = true;
            app.PlotpowerdissipationspectrumatCheckBox = uicheckbox(app.BottomEmittingOLEDSimulatorUIFigure);
            app.PlotpowerdissipationspectrumatCheckBox.Text = 'Plot power dissipation spectrum at';
            app.PlotpowerdissipationspectrumatCheckBox.Value = true;
            app.Wavelength2EditField = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'numeric');
            app.Wavelength2EditField.Value = 500;
            app.nmLabel_2 = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.nmLabel_2.Text = 'nm';
            app.Thick9Field = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'text');
            app.Thick9Field.Value = 'NaN';
            app.Layer9DropDownLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer9DropDownLabel.Text = 'Layer 9';
            app.Layer9DropDown = uidropdown(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer9DropDown.Items = {'N/A', 'Ag', 'Air', 'Al', 'Alq3', 'BAlq', 'Bphen', 'CBP', 'Glass', 'ITO', 'MoO3', 'NPB', 'NDP9', 'NHT5', 'NHT49', 'NHT49_NDP9_10pr', 'Spiro_TAD', 'TPBi', 'PolyTPD', 'MAPbI'};
            app.Layer9DropDown.Value = 'N/A';
            app.Thick10Field = uieditfield(app.BottomEmittingOLEDSimulatorUIFigure, 'text');
            app.Thick10Field.Value = 'NaN';
            app.Layer10DropDownLabel = uilabel(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer10DropDownLabel.Text = 'Layer 10';
            app.Layer10DropDown = uidropdown(app.BottomEmittingOLEDSimulatorUIFigure);
            app.Layer10DropDown.Items = {'N/A', 'Ag', 'Air', 'Al', 'Alq3', 'BAlq', 'Bphen', 'CBP', 'Glass', 'ITO', 'MoO3', 'NPB', 'NDP9', 'NHT5', 'NHT49', 'NHT49_NDP9_10pr', 'Spiro_TAD', 'TPBi', 'PolyTPD', 'MAPbI'};
            app.Layer10DropDown.Value = 'N/A';
            % Show the figure after all components are created
            app.BottomEmittingOLEDSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appIn

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BottomEmittingOLEDSimulatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BottomEmittingOLEDSimulatorUIFigure)
        end
    end
end